classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        MultiplierLabel         matlab.ui.control.Label
        SensitivitySlider       matlab.ui.control.Slider
        OutlierSTDLabel         matlab.ui.control.Label
        IdentifyOutliersButton  matlab.ui.control.Button
        LoadDataButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        OutliersVisible = false; % Tracks if outliers are currently displayed
    end
    
    methods (Access = private)
        
        % Main function to handle outlier detection and plotting (no changes needed here)
        function updateOutliers(app)
            % Ensure there is data plotted on the UIAxes
            if isempty(app.UIAxes.Children)
                % Silently return if no data, button press will show alert
                return;
            end

            % --- Step 1: Get Data from the Plot ---
            lineObject = findobj(app.UIAxes.Children, 'Type', 'line', '-and', '-not', 'Tag', 'outlier_plot');
            if isempty(lineObject)
                return; % No data line found
            end
            x_data = lineObject(1).XData;
            y_data = lineObject(1).YData;

            % --- Step 2: Clear Previous Outlier Circles ---
            % Find any previous outlier plots by looking for their 'Tag'
            previous_outliers = findobj(app.UIAxes, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end

            % --- Step 3: Identify Outliers using Moving Average ---
            % Get the multiplier value directly from the slider
            std_multiplier = app.SensitivitySlider.Value;

            % Update the label to show just the current numeric value
            app.MultiplierLabel.Text = sprintf('%.2f', std_multiplier);

            window_size = 20;
            moving_avg = movmean(y_data, window_size);
            residuals = y_data - moving_avg;
            residual_std = std(residuals);
            threshold = std_multiplier * residual_std;
            outlier_indices = find(abs(residuals) > threshold);

            % --- Step 4: Plot New Outliers ---
            if ~isempty(outlier_indices)
                x_outliers = x_data(outlier_indices);
                y_outliers = y_data(outlier_indices);

                hold(app.UIAxes, 'on');
                
                % Plot the new outliers and add a 'Tag' for easy removal later
                plot(app.UIAxes, x_outliers, y_outliers, 'ro', ...
                    'MarkerSize', 10, ...
                    'LineWidth', 2, ...
                    'DisplayName', 'Outliers', ...
                    'Tag', 'outlier_plot'); % Tag this plot
                
                hold(app.UIAxes, 'off');
            end
            
            % Refresh the legend
            legend(app.UIAxes, 'show');
        end

                % Function to specifically hide the outlier circles
        function hideOutliers(app)
            % Find any previous outlier plots by their 'Tag' and delete them
            previous_outliers = findobj(app.UIAxes, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end
            % Refresh the legend to remove the 'Outliers' entry
            legend(app.UIAxes, 'show');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide the sensitivity slider and its label initially
            app.SensitivitySlider.Visible = 'off';
            app.MultiplierLabel.Visible = 'off';
            app.OutlierSTDLabel.Visible = 'off';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Open a dialog box for the user to select a .csv file.
            [fileName, filePath] = uigetfile('*.csv', 'Select a Reactor Data CSV file');
            
            % Check if the user clicked "Cancel" on the file dialog.
            if isequal(fileName, 0)
                return;
            end
            
            % Create the full path to the selected file.
            fullPath = fullfile(filePath, fileName);
            
            try
                % Read the data from the CSV file into a table.
                data = readtable(fullPath);
                
                % --- START OF MODIFIED COLUMN FINDING LOGIC ---
                
                % Get all column names from the table
                colNames = data.Properties.VariableNames;
                
                % Find the first column containing 'Time', 'Temp', and 'Pressure' (case-insensitive)
                timeColIdx = find(contains(colNames, 'Time', 'IgnoreCase', true), 1);
                tempColIdx = find(contains(colNames, 'Temp', 'IgnoreCase', true), 1);
                pressureColIdx = find(contains(colNames, 'Pressure', 'IgnoreCase', true), 1);
                
                % Check if all required columns were found
                if ~isempty(timeColIdx) && ~isempty(tempColIdx) && ~isempty(pressureColIdx)
                    
                    % Extract data using the found column indices
                    timeData = data{:, timeColIdx};
                    tempData = data{:, tempColIdx};
                    pressureData = data{:, pressureColIdx};
                    
                    % --- END OF MODIFIED COLUMN FINDING LOGIC ---
                    
                    % --- DUAL-AXIS PLOTTING CODE (Now uses flexible data variables) ---
            
                    % Clear the axes before drawing a new plot
                    cla(app.UIAxes, 'reset');
            
                    % Activate the left y-axis for Temperature
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes, timeData, tempData, 'b-', 'LineWidth', 1.5);
                    
                    % Label the left axis and set its color
                    ylabel(app.UIAxes, 'Temperature (°C)', 'Color', 'b');
                    app.UIAxes.YColor = 'b';
            
                    % Activate the right y-axis for Pressure
                    yyaxis(app.UIAxes, 'right');
                    % CORRECTED THIS LINE: Plot pressureData against timeData
                    plot(app.UIAxes, timeData, pressureData, 'r-', 'LineWidth', 1.5);
                    
                    % Label the right axis and set its color
                    ylabel(app.UIAxes, 'Pressure (Pa)', 'Color', 'r');
                    app.UIAxes.YColor = 'r';
            
                    % Add title, x-axis label, legend, and grid
                    safeFileName = strrep(fileName, '_', ' ');
                    title(app.UIAxes, ['Data from ', safeFileName]);
                    xlabel(app.UIAxes, 'Time (sec)');
                    legend(app.UIAxes, 'Temperature', 'Pressure', 'Location', 'northwest');
                    grid(app.UIAxes, 'on');
        
                else
                    % If the file is missing required columns, show an alert to the user.
                    missingColsMsg = sprintf('Selected file must have columns containing the words "Time", "Temp", and "Pressure".');
                    uialert(app.UIFigure, missingColsMsg, 'Invalid File Format');
                end
                
            catch ME
                % If any error occurs (e.g., file is not a valid CSV),
                % display an error message in an alert dialog.
                uialert(app.UIAxes, ['Error loading file: ' ME.message], 'Error');
            end
    
        end

        % Button pushed function: IdentifyOutliersButton
        function IdentifyOutliersButtonPushed(app, event)
            % Toggle the visibility state
            app.OutliersVisible = ~app.OutliersVisible;

            if app.OutliersVisible
                % --- STATE IS ON ---
                % Run the analysis to show the outliers
                app.updateOutliers();
                
                % Update button text and show the slider controls
                app.IdentifyOutliersButton.Text = 'Hide Outliers';
                app.SensitivitySlider.Visible = 'on';
                app.MultiplierLabel.Visible = 'on';
                app.OutlierSTDLabel.Visible = 'on';
            else
                % --- STATE IS OFF ---
                % Hide the outliers
                app.hideOutliers();
                
                % Update button text and hide the slider controls
                app.IdentifyOutliersButton.Text = 'Identify Outliers';
                app.SensitivitySlider.Visible = 'off';
                app.MultiplierLabel.Visible = 'off';
                app.OutlierSTDLabel.Visible = 'off';
            end

        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            % When the slider value changes, just re-run the outlier detection
            app.updateOutliers();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.IdentifyOutliersButton = uibutton(app.LeftPanel, 'push');
            app.IdentifyOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyOutliersButtonPushed, true);
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.OutlierSTDLabel = uilabel(app.LeftPanel);
            app.OutlierSTDLabel.Text = 'Outlier STD:';
            app.SensitivitySlider = uislider(app.LeftPanel);
            app.SensitivitySlider.Limits = [0.5 4];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 2.5;
            app.MultiplierLabel = uilabel(app.LeftPanel);
            app.MultiplierLabel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end